classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LiveButton               matlab.ui.control.StateButton
        AutoCentreButton         matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        GridSwitchLabel          matlab.ui.control.Label
        GridSwitch               matlab.ui.control.Switch
        ShootingPanel            matlab.ui.container.Panel
        MovetoBeamButton         matlab.ui.control.Button
        ZaxisoffsetLabel         matlab.ui.control.Label
        ZaxisoffsetSpinner       matlab.ui.control.Spinner
        PulseWidthmsLabel        matlab.ui.control.Label
        PulseWidthmsSpinner      matlab.ui.control.Spinner
        NoshotsSpinnerLabel      matlab.ui.control.Label
        NoshotsSpinner           matlab.ui.control.Spinner
        MililngPanel             matlab.ui.container.Panel
        NoringsSpinnerLabel      matlab.ui.control.Label
        NoringsSpinner           matlab.ui.control.Spinner
        FreqSpinnerLabel         matlab.ui.control.Label
        FreqSpinner              matlab.ui.control.Spinner
        RotationSpinnerLabel     matlab.ui.control.Label
        RotationSpinner          matlab.ui.control.Spinner
        DirectionSwitchLabel     matlab.ui.control.Label
        DirectionSwitch          matlab.ui.control.Switch
        StyleSwitchLabel         matlab.ui.control.Label
        StyleSwitch              matlab.ui.control.Switch
        RandomSwitchLabel        matlab.ui.control.Label
        RandomSwitch             matlab.ui.control.Switch
        EccentricityPanel        matlab.ui.container.Panel
        AngleSpinnerLabel        matlab.ui.control.Label
        AngleSpinner             matlab.ui.control.Spinner
        RadiiratioSpinnerLabel   matlab.ui.control.Label
        RadiiratioSpinner        matlab.ui.control.Spinner
        RadiusSpinnerLabel       matlab.ui.control.Label
        RadiusSpinner            matlab.ui.control.Spinner
        BeamPositionPanel        matlab.ui.container.Panel
        xEditField_2Label        matlab.ui.control.Label
        xEditField_2             matlab.ui.control.NumericEditField
        yEditField_2Label        matlab.ui.control.Label
        yEditField_2             matlab.ui.control.NumericEditField
        zEditField_2Label        matlab.ui.control.Label
        zEditField_2             matlab.ui.control.NumericEditField
        mmLabel                  matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        ShootButton              matlab.ui.control.Button
        BSLampLabel              matlab.ui.control.Label
        BSLamp                   matlab.ui.control.Lamp
        FiberLampLabel           matlab.ui.control.Label
        FiberLamp                matlab.ui.control.Lamp
        GridCheckBox             matlab.ui.control.CheckBox
        ShootingSwitch           matlab.ui.control.Switch
        MaxContrastButton        matlab.ui.control.Button
        XYZstagesPanel           matlab.ui.container.Panel
        UpButton                 matlab.ui.control.Button
        DownButton               matlab.ui.control.Button
        LeftButton               matlab.ui.control.Button
        RightButton              matlab.ui.control.Button
        OutButton                matlab.ui.control.Button
        InButton                 matlab.ui.control.Button
        StepSizePanel            matlab.ui.container.Panel
        x10Button                matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        StepSizeEditField        matlab.ui.control.NumericEditField
        umLabel                  matlab.ui.control.Label
        yEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        zEditField               matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        TabGroup2                matlab.ui.container.TabGroup
        SurfaceTab               matlab.ui.container.Tab
        MoveButton_2             matlab.ui.control.Button
        SavepositionButton_2     matlab.ui.control.Button
        FiberTab                 matlab.ui.container.Tab
        MoveButton               matlab.ui.control.Button
        SavepositionButton       matlab.ui.control.Button
        StartingpositionfrommaxcontrastLabel  matlab.ui.control.Label
        StartingpositionfrommaxcontrastSpinner  matlab.ui.control.Spinner
        Label                    matlab.ui.control.Label
        ReconstructFiberButton   matlab.ui.control.Button
        CleaveAngleSpinnerLabel  matlab.ui.control.Label
        CleaveAngleSpinner       matlab.ui.control.Spinner
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        step_size = 1;
        C885 = 1;
        AtCube = 1;
    end
    
    methods (Access = private)
        function [I] = grabImage(app)
            [err, M, w, h] = uEye_camera(1); % fetch image
            M = reshape(M+128, 3, w*h);
            I = reshape(M, 3, w, h);
            I = permute(I, [3 2 1])/255;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uEye_camera(0); % initializing camera
            uEye_camera(8, 10); %exposure time

            % For PI
            clear C885
            addpath ('C:\Users\Public\PI\PI_MATLAB_Driver_GCS2')
            % For attocube
            clear AtCube
            PI_Controller = PI_GCS_Controller ();
            PI_Controller.Destroy();
            app.C885 = PI_control();
            app.AtCube = AtCube_control();
            opt = processPropertyValuePairs({'loadsettings'}, varargin, 'struct', true);
%             loadsettings = true;
%             if isfield(opt, 'loadsettings') && ~opt.loadsettings
%                 loadsettings = false;  
%             end
            
            app.AtCube.turnOn()
        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
            value = app.LiveButton.Value;    
            while value == true
                    I = grabImage(app);
                    app.Image.ImageSource = I;
                    pause(0.1);
                end
        end

        % Callback function
        function CloseButtonValueChanged(app, event)
            uEye_camera(4);
            close all
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
             y_pos = app.C885.getPosition_y();
             app.C885.move_y(y_pos-app.step_size);
        end

        % Callback function
        function ButtonValueChanged(app, event)
            if app.ssLarge.value == true
                app.step_size = 5;
            end
            if app.ssBig.value == true
                app.step_size = 1;
            end
            if app.ssSmall.value == true
                app.step_size = 0.5;
            end
            if app.ssTiny.value == true
                app.step_size = 0.1;
            end
        end

        % Callback function
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            app.step_size = 0.5;
        end

        % Callback function
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            app.step_size = 1;
        end

        % Callback function
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;
            app.step_size = 5;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            uEye_camera(4);
            app.C885.delete()
            app.AtCube.delete()
            delete(app)
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
             y_pos = app.C885.getPosition_y();
             app.C885.move_y(y_pos+app.step_size);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            x_pos = app.C885.getPosition_x();
            app.C885.move_x(x_pos+app.step_size);
        end

        % Button pushed function: InButton
        function InButtonPushed(app, event)
            z_pos = app.AtCube.getPosition_z();
            app.AtCube.move_z(z_pos+app.step_size);
        end

        % Button pushed function: OutButton
        function OutButtonPushed(app, event)
            z_pos = app.AtCube.getPosition_z();
            app.AtCube.move_z(z_pos-app.step_size);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            x_pos = app.C885.getPosition_x();
            app.C885.move_x(x_pos-app.step_size);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: GridSwitch
        function GridSwitchValueChanged(app, event)
            value = app.GridSwitch.Value;
            
        end

        % Callback function: Button_3, StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
        end

        % Value changed function: 
        % StartingpositionfrommaxcontrastSpinner
        function StartingpositionfrommaxcontrastSpinnerValueChanged(app, event)
            value = app.StartingpositionfrommaxcontrastSpinner.Value;
            
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            value = app.zEditField.Value;
            
        end

        % Value changed function: xEditField_2
        function xEditField_2ValueChanged(app, event)
            value = app.xEditField_2.Value;
            
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
            value = app.yEditField_2.Value;
            
        end

        % Value changed function: zEditField_2
        function zEditField_2ValueChanged(app, event)
            value = app.zEditField_2.Value;
            
        end

        % Value changed function: NoshotsSpinner
        function NoshotsSpinnerValueChanged(app, event)
            value = app.NoshotsSpinner.Value;
            
        end

        % Button pushed function: SavepositionButton
        function SavepositionButtonPushed(app, event)
            
        end

        % Value changed function: ZaxisoffsetSpinner
        function ZaxisoffsetSpinnerValueChanged(app, event)
            value = app.ZaxisoffsetSpinner.Value;
            
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            value = app.AngleSpinner.Value;
            
        end

        % Value changed function: RadiiratioSpinner
        function RadiiratioSpinnerValueChanged(app, event)
            value = app.RadiiratioSpinner.Value;
            
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            
        end

        % Value changed function: ShootingSwitch
        function ShootingSwitchValueChanged(app, event)
            value = app.ShootingSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LiveButton = uibutton(app.UIFigure, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Text = 'Live';
            app.AutoCentreButton = uibutton(app.UIFigure, 'push');
            app.AutoCentreButton.Text = {'Auto-Centre'; ''};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.GridSwitchLabel = uilabel(app.UIFigure);
            app.GridSwitchLabel.Text = 'Grid';
            app.GridSwitch = uiswitch(app.UIFigure, 'slider');
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @GridSwitchValueChanged, true);
            app.GridSwitch.Value = 'On';
            app.ShootingPanel = uipanel(app.UIFigure);
            app.ShootingPanel.Title = 'Shooting';
            app.MovetoBeamButton = uibutton(app.ShootingPanel, 'push');
            app.MovetoBeamButton.Text = 'Move to Beam';
            app.ZaxisoffsetLabel = uilabel(app.ShootingPanel);
            app.ZaxisoffsetLabel.Text = {'Z-axis'; 'offset'};
            app.ZaxisoffsetSpinner = uispinner(app.ShootingPanel);
            app.ZaxisoffsetSpinner.Step = 0.1;
            app.ZaxisoffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @ZaxisoffsetSpinnerValueChanged, true);
            app.PulseWidthmsLabel = uilabel(app.ShootingPanel);
            app.PulseWidthmsLabel.Text = {'Pulse '; 'Width (ms)'};
            app.PulseWidthmsSpinner = uispinner(app.ShootingPanel);
            app.NoshotsSpinnerLabel = uilabel(app.ShootingPanel);
            app.NoshotsSpinnerLabel.Text = 'No. shots';
            app.NoshotsSpinner = uispinner(app.ShootingPanel);
            app.NoshotsSpinner.Limits = [1 Inf];
            app.NoshotsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoshotsSpinnerValueChanged, true);
            app.NoshotsSpinner.Value = 1;
            app.MililngPanel = uipanel(app.ShootingPanel);
            app.MililngPanel.Title = 'Mililng';
            app.NoringsSpinnerLabel = uilabel(app.MililngPanel);
            app.NoringsSpinnerLabel.Text = 'No. rings';
            app.NoringsSpinner = uispinner(app.MililngPanel);
            app.FreqSpinnerLabel = uilabel(app.MililngPanel);
            app.FreqSpinnerLabel.Text = 'Freq';
            app.FreqSpinner = uispinner(app.MililngPanel);
            app.RotationSpinnerLabel = uilabel(app.MililngPanel);
            app.RotationSpinnerLabel.Text = 'Rotation';
            app.RotationSpinner = uispinner(app.MililngPanel);
            app.DirectionSwitchLabel = uilabel(app.MililngPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.MililngPanel, 'slider');
            app.DirectionSwitch.Items = {'Winter', 'Summer'};
            app.DirectionSwitch.Value = 'Winter';
            app.StyleSwitchLabel = uilabel(app.MililngPanel);
            app.StyleSwitchLabel.Text = 'Style';
            app.StyleSwitch = uiswitch(app.MililngPanel, 'slider');
            app.StyleSwitch.Items = {'Radial', 'C.entric'};
            app.StyleSwitch.Value = 'Radial';
            app.RandomSwitchLabel = uilabel(app.MililngPanel);
            app.RandomSwitchLabel.Text = 'Random';
            app.RandomSwitch = uiswitch(app.MililngPanel, 'slider');
            app.EccentricityPanel = uipanel(app.MililngPanel);
            app.EccentricityPanel.Title = 'Eccentricity';
            app.AngleSpinnerLabel = uilabel(app.EccentricityPanel);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.EccentricityPanel);
            app.AngleSpinner.Limits = [0 89];
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.RadiiratioSpinnerLabel = uilabel(app.EccentricityPanel);
            app.RadiiratioSpinnerLabel.Text = 'Radii ratio';
            app.RadiiratioSpinner = uispinner(app.EccentricityPanel);
            app.RadiiratioSpinner.Step = 0.1;
            app.RadiiratioSpinner.Limits = [0 3];
            app.RadiiratioSpinner.ValueChangedFcn = createCallbackFcn(app, @RadiiratioSpinnerValueChanged, true);
            app.RadiusSpinnerLabel = uilabel(app.MililngPanel);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.MililngPanel);
            app.BeamPositionPanel = uipanel(app.ShootingPanel);
            app.BeamPositionPanel.Title = 'Beam Position';
            app.xEditField_2Label = uilabel(app.BeamPositionPanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.BeamPositionPanel, 'numeric');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditField_2ValueChanged, true);
            app.yEditField_2Label = uilabel(app.BeamPositionPanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.BeamPositionPanel, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.zEditField_2Label = uilabel(app.BeamPositionPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.BeamPositionPanel, 'numeric');
            app.zEditField_2.ValueChangedFcn = createCallbackFcn(app, @zEditField_2ValueChanged, true);
            app.mmLabel = uilabel(app.BeamPositionPanel);
            app.mmLabel.Text = '(mm)';
            app.Lamp = uilamp(app.ShootingPanel);
            app.ShootButton = uibutton(app.ShootingPanel, 'push');
            app.ShootButton.Text = 'Shoot';
            app.BSLampLabel = uilabel(app.ShootingPanel);
            app.BSLampLabel.Text = 'BS';
            app.BSLamp = uilamp(app.ShootingPanel);
            app.FiberLampLabel = uilabel(app.ShootingPanel);
            app.FiberLampLabel.Text = 'Fiber';
            app.FiberLamp = uilamp(app.ShootingPanel);
            app.GridCheckBox = uicheckbox(app.ShootingPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.ShootingSwitch = uiswitch(app.ShootingPanel, 'slider');
            app.ShootingSwitch.Items = {'Single', 'Millling'};
            app.ShootingSwitch.ValueChangedFcn = createCallbackFcn(app, @ShootingSwitchValueChanged, true);
            app.ShootingSwitch.Value = 'Single';
            app.MaxContrastButton = uibutton(app.UIFigure, 'push');
            app.MaxContrastButton.Text = 'Max Contrast';
            app.XYZstagesPanel = uipanel(app.UIFigure);
            app.XYZstagesPanel.Title = 'XYZ stages';
            app.UpButton = uibutton(app.XYZstagesPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.XYZstagesPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.XYZstagesPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.XYZstagesPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.OutButton = uibutton(app.XYZstagesPanel, 'push');
            app.OutButton.ButtonPushedFcn = createCallbackFcn(app, @OutButtonPushed, true);
            app.OutButton.Text = 'Out';
            app.InButton = uibutton(app.XYZstagesPanel, 'push');
            app.InButton.ButtonPushedFcn = createCallbackFcn(app, @InButtonPushed, true);
            app.InButton.Text = 'In';
            app.StepSizePanel = uipanel(app.XYZstagesPanel);
            app.StepSizePanel.Title = 'Step Size';
            app.x10Button = uibutton(app.StepSizePanel, 'push');
            app.x10Button.Text = 'x10';
            app.Button_2 = uibutton(app.StepSizePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-1';
            app.Button_3 = uibutton(app.StepSizePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.Button_3.Text = '+1';
            app.Button_4 = uibutton(app.StepSizePanel, 'push');
            app.Button_4.Text = '/10';
            app.StepSizeEditField = uieditfield(app.StepSizePanel, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 200;
            app.umLabel = uilabel(app.StepSizePanel);
            app.umLabel.Text = 'um';
            app.yEditFieldLabel = uilabel(app.XYZstagesPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.XYZstagesPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.zEditFieldLabel = uilabel(app.XYZstagesPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.XYZstagesPanel, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.xEditFieldLabel = uilabel(app.XYZstagesPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.XYZstagesPanel, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.4f';
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.XYZstagesPanel);
            app.SurfaceTab = uitab(app.TabGroup2);
            app.SurfaceTab.Title = 'Surface';
            app.MoveButton_2 = uibutton(app.SurfaceTab, 'push');
            app.MoveButton_2.Text = 'Move';
            app.SavepositionButton_2 = uibutton(app.SurfaceTab, 'push');
            app.SavepositionButton_2.Text = 'Save position';
            app.FiberTab = uitab(app.TabGroup2);
            app.FiberTab.Title = 'Fiber';
            app.MoveButton = uibutton(app.FiberTab, 'push');
            app.MoveButton.Text = 'Move';
            app.SavepositionButton = uibutton(app.FiberTab, 'push');
            app.SavepositionButton.ButtonPushedFcn = createCallbackFcn(app, @SavepositionButtonPushed, true);
            app.SavepositionButton.Text = 'Save position';
            app.StartingpositionfrommaxcontrastLabel = uilabel(app.UIFigure);
            app.StartingpositionfrommaxcontrastLabel.Text = {'Starting position'; ' from max contrast'};
            app.StartingpositionfrommaxcontrastSpinner = uispinner(app.UIFigure);
            app.StartingpositionfrommaxcontrastSpinner.Step = 0.5;
            app.StartingpositionfrommaxcontrastSpinner.Limits = [0 10];
            app.StartingpositionfrommaxcontrastSpinner.ValueChangedFcn = createCallbackFcn(app, @StartingpositionfrommaxcontrastSpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'λ';
            app.ReconstructFiberButton = uibutton(app.UIFigure, 'push');
            app.ReconstructFiberButton.Text = 'Reconstruct Fiber';
            app.CleaveAngleSpinnerLabel = uilabel(app.UIFigure);
            app.CleaveAngleSpinnerLabel.Text = {'Cleave'; 'Angle'};
            app.CleaveAngleSpinner = uispinner(app.UIFigure);
            app.CleaveAngleSpinner.Step = 5;
            app.CleaveAngleSpinner.Limits = [100 300];
            app.CleaveAngleSpinner.Value = 200;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end